classdef SelenoidUpdated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelenoidSimlasyonUIFigure  matlab.ui.Figure
        REditFieldLabel            matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel            matlab.ui.control.Label
        dEditField                 matlab.ui.control.NumericEditField
        NEditFieldLabel            matlab.ui.control.Label
        NEditField                 matlab.ui.control.NumericEditField
        UIAxes                     matlab.ui.control.UIAxes
        SimulationButton           matlab.ui.control.Button
        LEditFieldLabel            matlab.ui.control.Label
        LEditField                 matlab.ui.control.NumericEditField
        YarapLabel                 matlab.ui.control.Label
        UzaklkLabel                matlab.ui.control.Label
        SarmSaysLabel              matlab.ui.control.Label
        UzunlukLabel               matlab.ui.control.Label
        MalzemeTrDropDownLabel     matlab.ui.control.Label
        MalzemeTrDropDown          matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            I = 1;
            r = app.REditField.Value;
            d = app.dEditField.Value;
            z = [-d:.1:d];
            n = app.NEditField.Value;
            L = app.LEditField.Value;
            value = app.MalzemeTrDropDown.Value;
            if (strcmp(value,'Kobalt'))
                t = 600;
            elseif (strcmp(value,'Demir'))
                t = 9000;
            elseif (strcmp(value,'Nikel'))
                t = 250;
            elseif (strcmp(value,'Alüminyum'))
                t = 1.000021;
            elseif (strcmp(value,'Titanyum'))
                t = 1.00018;
            elseif (strcmp(value,'Altın'))
                t = 0.99983;
            elseif (strcmp(value,'Gümüş'))
                t = 0.99998;
            elseif (strcmp(value,'Bakır'))
                t = 0.99999;
            end
            H =  (0.5*n*I/L).*[((z+L/2)./(sqrt((z+L/2).^2+r.^2)))-((z-L/2)./(sqrt((z-L/2).^2+r.^2)))];
            B = H .* t;
            plot(app.UIAxes,z,B);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelenoidSimlasyonUIFigure = uifigure;
            app.SelenoidSimlasyonUIFigure.Name = 'Selenoid Simülasyon';
            app.REditFieldLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SelenoidSimlasyonUIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.SelenoidSimlasyonUIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.SelenoidSimlasyonUIFigure, 'numeric');
            app.UIAxes = uiaxes(app.SelenoidSimlasyonUIFigure);
            title(app.UIAxes, 'Manyetik Alan Değişimi')
            xlabel(app.UIAxes, 'Merkezden Uzaklık')
            ylabel(app.UIAxes, 'Manyetik Alan (B)')
            app.SimulationButton = uibutton(app.SelenoidSimlasyonUIFigure, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.LEditFieldLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.SelenoidSimlasyonUIFigure, 'numeric');
            app.YarapLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.YarapLabel.Text = 'Yarı Çap';
            app.UzaklkLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.UzaklkLabel.Text = 'Uzaklık';
            app.SarmSaysLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.SarmSaysLabel.Text = 'Sarım Sayısı';
            app.UzunlukLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.UzunlukLabel.Text = 'Uzunluk';
            app.MalzemeTrDropDownLabel = uilabel(app.SelenoidSimlasyonUIFigure);
            app.MalzemeTrDropDownLabel.Text = 'Malzeme Türü';
            app.MalzemeTrDropDown = uidropdown(app.SelenoidSimlasyonUIFigure);
            app.MalzemeTrDropDown.Items = {'Kobalt', 'Demir', 'Nikel', 'Alüminyum', 'Titanyum', 'Altın', 'Gümüş', 'Bakır'};
            app.MalzemeTrDropDown.Value = 'Kobalt';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SelenoidUpdated

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelenoidSimlasyonUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelenoidSimlasyonUIFigure)
        end
    end
end